classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ResultLabel_2     matlab.ui.control.Label
        ResultLabel       matlab.ui.control.Label
        CalculateButton   matlab.ui.control.Button
        CNNResultLabel    matlab.ui.control.Label
        KNNResultLabel    matlab.ui.control.Label
        YOLOLabel         matlab.ui.control.Label
        InputImageButton  matlab.ui.control.Button
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageInput = nan;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes)
            disableDefaultInteractivity(app.UIAxes_2)
        end

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp;*.jpg;*.jpeg;*.png'},"Open File");
            fullpath = strcat(pathname,filename);
            if(fullpath == "")
                warndlg("File Not Found")
            else
                image = imread(fullpath);
                app.ImageInput = imread(fullpath);
                imshow(image,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if(isnan(app.ImageInput))
                warndlg("Must Input Image First", "Input")
            else
                image = app.ImageInput;
                d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Indeterminate','on');
                [hasilcnn,label] = cnn(image);
                labelknn = knn(image);
                imshow(hasilcnn,'Parent',app.UIAxes_2);
                app.ResultLabel.Text = label;
                app.ResultLabel_2.Text = labelknn;
                close(d)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.InputImageButton = uibutton(app.UIFigure, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Input Image';
            app.YOLOLabel = uilabel(app.UIFigure);
            app.YOLOLabel.Text = 'YOLO';
            app.KNNResultLabel = uilabel(app.UIFigure);
            app.KNNResultLabel.Text = 'KNN Result';
            app.CNNResultLabel = uilabel(app.UIFigure);
            app.CNNResultLabel.Text = 'CNN Result';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.ResultLabel_2 = uilabel(app.UIFigure);
            app.ResultLabel_2.Text = 'Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end